classdef musicmixer_mlo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mixerFig                       matlab.ui.Figure
        LoopsSpinner                   matlab.ui.control.Spinner
        LoopsSpinnerLabel              matlab.ui.control.Label
        TempoSlider                    matlab.ui.control.Slider
        TempoSliderLabel               matlab.ui.control.Label
        Perc3Play                      matlab.ui.control.Button
        Perc2Play                      matlab.ui.control.Button
        Perc1Play                      matlab.ui.control.Button
        LeadPlay                       matlab.ui.control.Button
        PlayAllInstrumentsButton       matlab.ui.control.Button
        BeatTable                      matlab.ui.control.Table
        ThirdPercussionDropDown        matlab.ui.control.DropDown
        ThirdPercussionDropDownLabel   matlab.ui.control.Label
        SecondPercussionDropDown       matlab.ui.control.DropDown
        SecondPercussionDropDownLabel  matlab.ui.control.Label
        FirstPercussionDropDown        matlab.ui.control.DropDown
        FirstPercussionDropDownLabel   matlab.ui.control.Label
        LeadInstrumentDropDown         matlab.ui.control.DropDown
        LeadInstrumentDropDownLabel    matlab.ui.control.Label
        MusicMixerLabel                matlab.ui.control.Label
        SaveSongtoFileButton           matlab.ui.control.Button
        WavePlot                       matlab.ui.control.UIAxes
    end

    properties (Access = public)
        Property % Description

        letterVals = {'C3' 'D3' 'E3' 'F3' 'G3' 'A3' 'B3'...
                      'C4' 'D4' 'E4' 'F4' 'G4' 'A4' 'B4'...
                      'C5' 'D5' 'E5' 'F5' 'G5' 'A5' 'B5'...
                      'C6' 'D6' 'E6' 'F6' 'G6' 'A6' 'B6'};
        notes = {'C3' 'D3' 'E3' 'F3' 'G3' 'A3' 'B3' 'C4'};
        validNotes
        d
        a
        leadList
        percList
        song
        prevIndices=[1:28]; 
        tempo=44100;
        playState=true;
        soundOn = false;
        saveCount=1;
    end
    
    methods (Access = private)

        function ddPop(app)
            %Populate lead instrument list
            linfo = dir('audio_files');
            app.leadList = {linfo.name}.';
            app.leadList=app.leadList(~ismember(app.leadList,{'.','..','percussion'}));

            %Populate percussion instrument list
            pinfo = dir('audio_files/percussion');
            app.percList = {pinfo.name}.';
            app.percList = app.percList(~ismember(app.percList,{'.','..'}));

            %populate dropdown menus
            LeadInstrumentDropDownValueChanged(app);
            FirstPercussionDropDownValueChanged(app);
            SecondPercussionDropDownValueChanged(app);
            ThirdPercussionDropDownValueChanged(app);
        end

        function x=pad(~,A,L) %pad arrays as necessary

            %find when the music starts within the audio file
            ind = find(abs(A) > 0.05);
            A=A(ind(1):end);
            
            %find the necessary padding length
            numZerosToPad = L - length(A);

            % Check if padding is necessary
            if numZerosToPad > 0
                % Pad array 'A' with zeros to the right to reach 'desiredLength'
                x = [A; zeros(numZerosToPad,1)];
            else
                % If 'A' is already equal to or larger than 'desiredLength', no padding is needed
                x = A(1:L);
            end
        end
        
        function pButtons(app,com)
            if com == "enable"
                set(app.PlayAllInstrumentsButton, 'Enable', 'on');
                set(app.LeadPlay, 'Enable', 'on');
                set(app.Perc1Play, 'Enable', 'on');
                set(app.Perc2Play, 'Enable', 'on');
                set(app.Perc3Play, 'Enable', 'on');
                set(app.TempoSlider, 'Enable', 'on');
                set(app.LoopsSpinner, 'Enable', 'on');
                set(app.SaveSongtoFileButton, 'Enable', 'on')
                set(app.LeadInstrumentDropDown, 'Enable', 'on')
                set(app.FirstPercussionDropDown, 'Enable', 'on')
                set(app.SecondPercussionDropDown, 'Enable', 'on')
                set(app.ThirdPercussionDropDown, 'Enable', 'on')
                app.BeatTable.ColumnEditable = true;

                app.PlayAllInstrumentsButton.BackgroundColor = [0.30,0.75,0.93];
                app.LeadPlay.BackgroundColor = 'green';
                app.Perc1Play.BackgroundColor = 'red';
                app.Perc2Play.BackgroundColor = 'yellow';
                app.Perc3Play.BackgroundColor = 'blue';
                app.SaveSongtoFileButton.BackgroundColor = [0.30,0.75,0.93];

            else
                set(app.PlayAllInstrumentsButton, 'Enable', 'off');
                set(app.LeadPlay, 'Enable', 'off');
                set(app.Perc1Play, 'Enable', 'off');
                set(app.Perc2Play, 'Enable', 'off');
                set(app.Perc3Play, 'Enable', 'off');
                set(app.TempoSlider, 'Enable', 'off');
                set(app.LoopsSpinner, 'Enable', 'off');
                set(app.SaveSongtoFileButton, 'Enable', 'off')
                set(app.LeadInstrumentDropDown, 'Enable', 'off')
                set(app.FirstPercussionDropDown, 'Enable', 'off')
                set(app.SecondPercussionDropDown, 'Enable', 'off')
                set(app.ThirdPercussionDropDown, 'Enable', 'off')
                app.BeatTable.ColumnEditable = false;

                app.PlayAllInstrumentsButton.BackgroundColor = [0.5,0.5,0.5];
                app.LeadPlay.BackgroundColor = [0.5,0.5,0.5];
                app.Perc1Play.BackgroundColor = [0.5,0.5,0.5];
                app.Perc2Play.BackgroundColor = [0.5,0.5,0.5];
                app.Perc3Play.BackgroundColor = [0.5,0.5,0.5];
                app.SaveSongtoFileButton.BackgroundColor = [0.5,0.5,0.5];
            end
        end

        function refreshTable(app,vN)
            t=table();
            b = categorical(vN);
            app.a = reordercats(b,vN);
            c=categorical("%");
            app.a=[c,app.a];

            app.BeatTable.Data = app.d;

            % actually refresh the values in the table
            for i=1:8
                g = app.a(app.a==app.notes(i));
                col = {g;cell2mat(app.d{2,i});cell2mat(app.d{3,i});cell2mat(app.d{4,i})};
                t = addvars(t,col);
            end

            app.d=t;
            app.BeatTable.Data = app.d;
        end

        function perc = composePerc(app,num,dd)
            percMat = table2cell(app.d(2:4,:));
            percMat = [percMat{:}];
            percMat = reshape(percMat,[3 8]);

            perc = [];
            rest = zeros(30000, 1);

            for beat = 1:8
                if percMat(num,beat) == 1
                    n = regexprep(dd,' ', '-');
                    searchDirectory = fullfile(pwd,'/audio_files/percussion/'+string(dd)+"/");

                    % Get a list of all files in the directory
                    files = dir(searchDirectory);

                    % Extract the names of the files
                    fileNames = {files.name};

                    % Use startsWith to find files that start with the specified string
                    matchingFiles = fileNames(startsWith(fileNames, n));

                    p = audioread(searchDirectory+string(matchingFiles(1)));
                    p = p/max(abs(p(:))) * 0.5;
                    p = app.pad(p,30000);

                else
                    p = rest;
                end

                perc = [perc; p];
            end
        end

        function l = composeLead(app)
            leadMat = table2cell(app.d(1,:));
            leadMat = string([leadMat{:}]);    

            l = [];
            rest = zeros(30000, 1);

            for beat = 1:8
                %LEAD
                if leadMat(1,beat) ~= "%"
                    n = regexprep(app.LeadInstrumentDropDown.Value,' ', '-');
                    searchString=n+"_"+leadMat(1,beat); %instrument name plus musical note

                    searchDirectory = fullfile(pwd,'/audio_files/'+string(app.LeadInstrumentDropDown.Value)+"/");

                    % Get a list of all files in the directory
                    files = dir(searchDirectory);

                    % Extract the names of the files
                    fileNames = {files.name};

                    % Use startsWith to find files that start with the specified string
                    matchingFiles = fileNames(startsWith(fileNames, searchString));

                    ld = audioread(searchDirectory+string(matchingFiles(1)));
                    ld = ld/max(abs(ld(:))) * 0.5;
                    ld=app.pad(ld,30000);

                else
                    ld = rest;
                end

                l = [l; ld];
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.mixerFig, 'center'); %center app window on screen
            pause(1);

            disableDefaultInteractivity(app.WavePlot);
            pan(app.WavePlot, 'off');
            zoom(app.WavePlot, 'off');

            % Establish beat table
            app.BeatTable.Data = app.d;

            % set parameters for editable table
            app.BeatTable.FontSize=32;
            s=uistyle('HorizontalAlignment','center');
            addStyle(app.BeatTable,s,"column",1:8);

            % populate table with all letter values
            b = categorical(app.letterVals);
            app.a = reordercats(b,app.letterVals);
            c=categorical("%");
            app.a=[c,app.a];

            % initialize table values first time
            col1 = {app.a(2);true;true;true};
            col2 = {app.a(3);false;false;false};
            col3 = {app.a(4);true;true;true};
            col4 = {app.a(5);false;false;false};
            col5 = {app.a(6);true;true;true};
            col6 = {app.a(7);false;false;false};
            col7 = {app.a(8);true;true;true};
            col8 = {app.a(10);false;false;false};

            app.d = table(col1,col2,col3,col4,col5,col6,col7,col8);
            app.BeatTable.Data = app.d;

            pButtons(app,"disable")

            if ~exist('audio_files', 'dir')
                hWaitbar = waitbar(0, 'Initializing...');

                try
                    %check if using desktop or matlab online
                    %if using online, download fewer audio files for speed
                    if usejava('desktop')
                        mkdir('zip_files');
                        mkdir('audio_files');

                        waitbar(0.1, hWaitbar, 'Downloading audio files...');

                        unzip("https://philharmonia-assets.s3-eu-west-1.amazonaws.com/uploads/2020/02/12112005/all-samples.zip", "zip_files");

                        waitbar(0.6, hWaitbar, 'Renaming directories...');

                        % Define the directory where the initial large zip was extracted
                        initialUnzipDir = fullfile(pwd, 'zip_files', 'all-samples');

                        % Find all nested zip files within the extracted directory
                        zipPattern = fullfile(initialUnzipDir, '*.zip');
                        zipFiles = dir(zipPattern);

                        waitbar(0.9, hWaitbar, 'Cleaning up...');

                        % Process each nested zip file
                        for k = 1:length(zipFiles)
                            [~, zipName, ~] = fileparts(zipFiles(k).name);

                            % Handle special case renaming
                            if strcmp(zipName, 'cor anglais')
                                zipName = 'english horn';
                            end

                            % Define the target directory for this nested zip's contents
                            unzipTargetDir = fullfile(pwd, 'audio_files', zipName);

                            % Check if the target directory exists, and create it if it doesn't
                            if ~exist(unzipTargetDir, 'dir')
                                mkdir(unzipTargetDir);
                            end

                            % Unzip the nested zip file into its target directory
                            zipFilePath = fullfile(initialUnzipDir, zipFiles(k).name);
                            unzip(zipFilePath, unzipTargetDir);

                            % Delete the nested zip file after extraction
                            delete(zipFilePath);
                        end

                    else
                        waitbar(0.1, hWaitbar, 'Downloading audio files...');

                        % matlabDrivePath = fullfile(userpath, '..', 'MATLAB Drive');
                        % selfPath = fullfile(matlabDrivePath, 'Repositories', 'selfie-sample');
                        % addpath(genpath(selfPath));

                        waitbar(0.3, hWaitbar, 'Downloading brass files...');
                        unzip("https://philharmonia-assets.s3-eu-west-1.amazonaws.com/uploads/2020/02/12112005/Brass.zip", "zip_files");
                        waitbar(0.6, hWaitbar, 'Downloading percussion files...');
                        unzip("https://philharmonia-assets.s3-eu-west-1.amazonaws.com/uploads/2020/02/12112005/Percussion.zip", "zip_files");

                        waitbar(0.8, hWaitbar, 'Renaming directories...');

                        % Get the full path of the unzip directory
                        unzipFullPath = fullfile(pwd, 'zip_files');

                        % Move contents of Brass to audio_files
                        movefile(fullfile(pwd, 'zip_files', 'Brass', '*'), fullfile(pwd, 'audio_files'));

                        % Move and rename the Percussion folder to be within all-samples as percussion
                        movefile(fullfile(unzipFullPath, 'Percussion'), fullfile(pwd, 'audio_files', 'percussion'));

                        % Define the directory to search within
                        baseDir = fullfile(pwd, 'audio_files');

                        waitbar(0.9, hWaitbar, 'Cleaning up...');

                        % Recursively find and delete all files and folders ending with .zip
                        zipItems = dir(fullfile(baseDir, '**', '*.zip'));
                        for k = 1:length(zipItems)
                            itemPath = fullfile(zipItems(k).folder, zipItems(k).name);
                            if zipItems(k).isdir
                                rmdir(itemPath, 's'); % Delete folder and its contents
                            else
                                delete(itemPath); % Delete file
                            end
                        end

                        % addpath(genpath(baseDir));

                    end

                    rmdir('zip_files', 's');

                    close(hWaitbar);

                catch e
                    % Show an error dialog if the unzip fails
                    uialert(app.mixerFig, ['Error unzipping file: ' e.message], 'Error');
                end
            end

            ddPop(app) %populate instrument and percussion dropdowns

            app.TempoSliderValueChanged

            pButtons(app,"enable")
        end

        % Button pushed function: SaveSongtoFileButton
        function SaveSongButtonPushed(app, event)
            pButtons(app,"disable")

            folderName = uigetdir('', 'Select a folder to save your files');
            if folderName == 0
                % user closed dialog
            else
                app.playState=false;
                PlayAllInstrumentsButtonPushed(app)

                filePath = fullfile(folderName, ('mySong'+string(app.saveCount)+".wav"));
                audiowrite(filePath,app.song,app.tempo);
            end

            app.playState=true;
            pButtons(app,"enable")
            app.saveCount=app.saveCount+1;
        end

        % Button pushed function: PlayAllInstrumentsButton
        function PlayAllInstrumentsButtonPushed(app, event)
            pButtons(app,"disable")

            if app.soundOn==true % if sound is playing, then stop it
                clear sound
            else
                app.soundOn = true;

                set(app.PlayAllInstrumentsButton, 'Enable', 'on')
                app.PlayAllInstrumentsButton.Text="Stop Play";
                app.PlayAllInstrumentsButton.BackgroundColor = [0.93,0.69,0.13];

                app.song = [];

                l = composeLead(app);
                p1 = composePerc(app,1,app.FirstPercussionDropDown.Value);
                p2 = composePerc(app,2,app.SecondPercussionDropDown.Value);
                p3 = composePerc(app,3,app.ThirdPercussionDropDown.Value);

                l_r = reshape(l,30000,[]);
                p1_r = reshape(p1,30000,[]);
                p2_r = reshape(p2,30000,[]);
                p3_r = reshape(p3,30000,[]);

                for beat = 1:8
                    x=l_r(:,beat)+p1_r(:,beat)+p2_r(:,beat)+p3_r(:,beat);
                    app.song = [app.song; x];
                end

                bufferL = [zeros(30000,1);l]; % make plot look nice on axes
                bufferP1 = [zeros(30000,1);p1]; % make plot look nice on axes
                bufferP2 = [zeros(30000,1);p2]; % make plot look nice on axes
                bufferP3 = [zeros(30000,1);p3]; % make plot look nice on axes

                t = (0:length(bufferL)-1)/44100;
                plot(app.WavePlot,t,(bufferP1/max(abs(bufferP1))),'red')
                hold(app.WavePlot,"on")
                plot(app.WavePlot,t,(bufferP2/max(abs(bufferP2))),'yellow')
                plot(app.WavePlot,t,(bufferP3/max(abs(bufferP3))),'blue')
                plot(app.WavePlot,t,(bufferL/max(abs(bufferL))),'green')
                hold(app.WavePlot,"off")

                %check number of loops added
                app.song=repmat(app.song,app.LoopsSpinner.Value,1);

                if app.playState==true
                    %play the song at user defined tempo
                    sound(app.song, app.tempo);
                    pause(length(app.song)/app.tempo);
                end

            end

            app.PlayAllInstrumentsButton.Text="Play All Instruments";
            app.soundOn = false;

            pButtons(app,"enable")

        end

        % Button pushed function: LeadPlay
        function LeadPlayPushed(app, event)
            pButtons(app,"disable")

            if app.soundOn==true % if sound is playing, then stop it
                clear sound
            else
                app.soundOn = true;
                set(app.LeadPlay, 'Enable', 'on')
                app.LeadPlay.Text="Stop";
                app.LeadPlay.BackgroundColor = [0.93,0.69,0.13];

                l = composeLead(app);

                bufferL = [zeros(30000,1);l]; % make plot look nice on axes
                t = (0:length(bufferL)-1)/44100;
                plot(app.WavePlot,t,(bufferL/max(abs(bufferL))),'green')

                %check number of loops added
                l=repmat(l,app.LoopsSpinner.Value,1);

                %play the song with a sampling frequency of 44100
                sound(l, app.tempo);
                pause(length(l)/app.tempo);

            end

            app.LeadPlay.Text="Play";
            app.soundOn = false;

            pButtons(app,"enable")

        end

        % Button pushed function: Perc1Play
        function Perc1PlayButtonPushed(app, event)
            pButtons(app,"disable")

            if app.soundOn==true % if sound is playing, then stop it
                clear sound
            else
                app.soundOn = true;
                set(app.Perc1Play, 'Enable', 'on')
                app.Perc1Play.Text="Stop";
                app.Perc1Play.BackgroundColor = [0.93,0.69,0.13];

                p1 = composePerc(app,1,app.FirstPercussionDropDown.Value);

                bufferP1 = [zeros(30000,1);p1]; % make plot look nice on axes
                t = (0:length(bufferP1)-1)/44100;
                plot(app.WavePlot,t,(bufferP1/max(abs(bufferP1))),'red')

                %check number of loops added
                p1=repmat(p1,app.LoopsSpinner.Value,1);

                %play the song with a sampling frequency of 44100
                sound(p1, app.tempo);
                pause(length(p1)/app.tempo);
            end

            app.Perc1Play.Text="Play";
            app.soundOn = false;

            pButtons(app,"enable")
        end

        % Button pushed function: Perc2Play
        function Perc2PlayButtonPushed(app, event)
            pButtons(app,"disable")

            if app.soundOn==true % if sound is playing, then stop it
                clear sound
            else
                app.soundOn = true;
                set(app.Perc2Play, 'Enable', 'on')
                app.Perc2Play.Text="Stop";
                app.Perc2Play.BackgroundColor = [0.93,0.69,0.13];

                p2 = composePerc(app,2,app.SecondPercussionDropDown.Value);

                bufferP2 = [zeros(30000,1);p2]; % make plot look nice on axes
                t = (0:length(bufferP2)-1)/44100;
                plot(app.WavePlot,t,(bufferP2/max(abs(bufferP2))),'yellow')

                %check number of loops added
                p2=repmat(p2,app.LoopsSpinner.Value,1);

                %play the song with a sampling frequency of 44100
                sound(p2, app.tempo);
                pause(length(p2)/app.tempo);
            end

            app.Perc2Play.Text="Play";
            app.soundOn = false;

            pButtons(app,"enable")
        end

        % Button pushed function: Perc3Play
        function Perc3PlayButtonPushed(app, event)
            pButtons(app,"disable")

            if app.soundOn==true % if sound is playing, then stop it
                clear sound
            else
                app.soundOn = true;
                set(app.Perc3Play, 'Enable', 'on')
                app.Perc3Play.Text="Stop";
                app.Perc3Play.BackgroundColor = [0.93,0.69,0.13];

                p3 = composePerc(app,3,app.ThirdPercussionDropDown.Value);

                bufferP3 = [zeros(30000,1);p3]; % make plot look nice on axes
                t = (0:length(bufferP3)-1)/44100;
                plot(app.WavePlot,t,(bufferP3/max(abs(bufferP3))),'blue')

                %check number of loops added
                p3=repmat(p3,app.LoopsSpinner.Value,1);

                %play the song with a sampling frequency of 44100
                sound(p3, app.tempo);
                pause(length(p3)/app.tempo);
            end

            app.Perc3Play.Text="Play";
            app.soundOn = false;

            pButtons(app,"enable")
        end

        % Value changed function: LeadInstrumentDropDown
        function LeadInstrumentDropDownValueChanged(app, event)
            set(app.LeadInstrumentDropDown, 'Items',string(app.leadList))

            % search instrument directory to find valid note options
            searchDirectory = fullfile(pwd,'/audio_files/'+string(app.LeadInstrumentDropDown.Value)+"/");
            searchStrings = app.letterVals;
            fileList = dir(searchDirectory);

            foundStrings = false(size(searchStrings)); % initialize an array to keep track of found strings

            for j = 1:length(searchStrings) % loop through every note value
                s = searchStrings{j};
                for i = 1:length(fileList) % loop through the file list
                    fileName = fileList(i).name; % current file name                   
                    if contains(fileName, s) % check if the file name contains the current search string
                        foundStrings(j) = true; % mark this search string as found
                    end
                end
            end

            validNotesIndices = find(foundStrings);
            app.validNotes = searchStrings(foundStrings);

            if ~all(ismember(app.prevIndices, validNotesIndices)) % if notes are not shared between instruments, then change/transpose all notes

                %get indices of data already within table
                leadMat = table2cell(app.d(1,:));
                leadMat = string([leadMat{:}]);

                [~, leadInd] = ismember(leadMat, app.letterVals);
                leadFind = find(leadInd ~= 0);
                leadFindZero = find(leadInd == 0);
                leadInd = leadInd(leadInd ~= 0);

                transVals = min(validNotesIndices) + ((leadInd - min(app.prevIndices)) .* (max(validNotesIndices) - min(validNotesIndices))) ./ (max(app.prevIndices) - min(app.prevIndices));
                transVals = ceil(transVals);

                for i = 1:length(transVals)
                    % Calculate the absolute differences with all elements in array2
                    differences = abs(validNotesIndices - transVals(i));

                    % Find the index of the minimum difference
                    [~, minIndex] = min(differences);

                    % Update the value in array1 to the nearest valid value in array2
                    transVals(i) = validNotesIndices(minIndex);
                end

                %put these values in the notes global variable
                app.notes(leadFind) = app.letterVals(transVals);
                app.notes(leadFindZero) = {'%'};
            end

            app.prevIndices = validNotesIndices;
            refreshTable(app,app.validNotes)
        end

        % Value changed function: FirstPercussionDropDown
        function FirstPercussionDropDownValueChanged(app, event)
            set(app.FirstPercussionDropDown, 'Items',string(app.percList));
        end

        % Value changed function: SecondPercussionDropDown
        function SecondPercussionDropDownValueChanged(app, event)
            set(app.SecondPercussionDropDown, 'Items',string(app.percList));
        end

        % Value changed function: ThirdPercussionDropDown
        function ThirdPercussionDropDownValueChanged(app, event)
            set(app.ThirdPercussionDropDown, 'Items',string(app.percList));
        end

        % Cell edit callback: BeatTable
        function BeatTableCellEdit(app, event)
            indices = event.Indices;     
            newData = event.NewData;

            if indices(1)==1 && ~ismember(newData, app.validNotes)
                newData=app.a(1);
            end

            app.BeatTable.Data{indices(1),indices(2)}={newData};
            app.d = app.BeatTable.Data;
        end

        % Value changed function: TempoSlider
        function TempoSliderValueChanged(app, event)
            app.tempo = app.TempoSlider.Value;
            % Calculate the bpm of the audio being played
            bpm = round(1/((30000/app.tempo)/60));
            app.TempoSliderLabel.Text="Tempo: "+string(bpm)+" BPM";
        end

        % Value changed function: LoopsSpinner
        function LoopsSpinnerValueChanged(app, event)
            value = app.LoopsSpinner.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mixerFig = uifigure('Visible', 'off');
            app.mixerFig.Name = 'MATLAB App';
            app.WavePlot = uiaxes(app.mixerFig);
            app.WavePlot.XTick = [];
            app.WavePlot.YTick = [];
            app.WavePlot.ZTick = [];
            app.SaveSongtoFileButton = uibutton(app.mixerFig, 'push');
            app.SaveSongtoFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSongButtonPushed, true);
            app.SaveSongtoFileButton.Enable = 'off';
            app.SaveSongtoFileButton.Text = 'Save Song to File';
            app.MusicMixerLabel = uilabel(app.mixerFig);
            app.MusicMixerLabel.Text = 'Music Mixer';
            app.LeadInstrumentDropDownLabel = uilabel(app.mixerFig);
            app.LeadInstrumentDropDownLabel.Text = 'Lead Instrument';
            app.LeadInstrumentDropDown = uidropdown(app.mixerFig);
            app.LeadInstrumentDropDown.Items = {};
            app.LeadInstrumentDropDown.ValueChangedFcn = createCallbackFcn(app, @LeadInstrumentDropDownValueChanged, true);
            app.LeadInstrumentDropDown.Value = {};
            app.FirstPercussionDropDownLabel = uilabel(app.mixerFig);
            app.FirstPercussionDropDownLabel.Text = 'First Percussion';
            app.FirstPercussionDropDown = uidropdown(app.mixerFig);
            app.FirstPercussionDropDown.Items = {};
            app.FirstPercussionDropDown.ValueChangedFcn = createCallbackFcn(app, @FirstPercussionDropDownValueChanged, true);
            app.FirstPercussionDropDown.Value = {};
            app.SecondPercussionDropDownLabel = uilabel(app.mixerFig);
            app.SecondPercussionDropDownLabel.Text = 'Second Percussion';
            app.SecondPercussionDropDown = uidropdown(app.mixerFig);
            app.SecondPercussionDropDown.Items = {};
            app.SecondPercussionDropDown.ValueChangedFcn = createCallbackFcn(app, @SecondPercussionDropDownValueChanged, true);
            app.SecondPercussionDropDown.Value = {};
            app.ThirdPercussionDropDownLabel = uilabel(app.mixerFig);
            app.ThirdPercussionDropDownLabel.Text = 'Third Percussion';
            app.ThirdPercussionDropDown = uidropdown(app.mixerFig);
            app.ThirdPercussionDropDown.Items = {};
            app.ThirdPercussionDropDown.ValueChangedFcn = createCallbackFcn(app, @ThirdPercussionDropDownValueChanged, true);
            app.ThirdPercussionDropDown.Value = {};
            app.BeatTable = uitable(app.mixerFig);
            app.BeatTable.ColumnName = {'Beat 1'; 'Beat 2'; 'Beat 3'; 'Beat 4'; 'Beat 5'; 'Beat 6'; 'Beat 7'; 'Beat 8'};
            app.BeatTable.RowName = {};
            app.BeatTable.ColumnEditable = [true true true true true true true true];
            app.BeatTable.CellEditCallback = createCallbackFcn(app, @BeatTableCellEdit, true);
            app.PlayAllInstrumentsButton = uibutton(app.mixerFig, 'push');
            app.PlayAllInstrumentsButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAllInstrumentsButtonPushed, true);
            app.PlayAllInstrumentsButton.Enable = 'off';
            app.PlayAllInstrumentsButton.Text = 'Play All Instruments';
            app.LeadPlay = uibutton(app.mixerFig, 'push');
            app.LeadPlay.ButtonPushedFcn = createCallbackFcn(app, @LeadPlayPushed, true);
            app.LeadPlay.Enable = 'off';
            app.LeadPlay.Text = 'Play';
            app.Perc1Play = uibutton(app.mixerFig, 'push');
            app.Perc1Play.ButtonPushedFcn = createCallbackFcn(app, @Perc1PlayButtonPushed, true);
            app.Perc1Play.Enable = 'off';
            app.Perc1Play.Text = 'Play';
            app.Perc2Play = uibutton(app.mixerFig, 'push');
            app.Perc2Play.ButtonPushedFcn = createCallbackFcn(app, @Perc2PlayButtonPushed, true);
            app.Perc2Play.Enable = 'off';
            app.Perc2Play.Text = 'Play';
            app.Perc3Play = uibutton(app.mixerFig, 'push');
            app.Perc3Play.ButtonPushedFcn = createCallbackFcn(app, @Perc3PlayButtonPushed, true);
            app.Perc3Play.Enable = 'off';
            app.Perc3Play.Text = 'Play';
            app.TempoSliderLabel = uilabel(app.mixerFig);
            app.TempoSliderLabel.Text = 'Tempo';
            app.TempoSlider = uislider(app.mixerFig);
            app.TempoSlider.Limits = [11025 132300];
            app.TempoSlider.MajorTicks = [];
            app.TempoSlider.ValueChangedFcn = createCallbackFcn(app, @TempoSliderValueChanged, true);
            app.TempoSlider.MinorTicks = [11025 13500 15975 18450 20925 23400 25875 28350 30825 33300 35775 38250 40725 43200 45675 48150 50625 53100 55575 58050 60525 63000 65475 67950 70425 72900 75375 77850 80325 82800 85275 87750 90225 92700 95175 97650 100125 102600 105075 107550 110025 112500 114975 117450 119925 122400 124875 127350 129825 132300];
            app.TempoSlider.Enable = 'off';
            app.TempoSlider.Value = 44100;
            app.LoopsSpinnerLabel = uilabel(app.mixerFig);
            app.LoopsSpinnerLabel.Text = 'Loops';
            app.LoopsSpinner = uispinner(app.mixerFig);
            app.LoopsSpinner.Limits = [1 16];
            app.LoopsSpinner.ValueChangedFcn = createCallbackFcn(app, @LoopsSpinnerValueChanged, true);
            app.LoopsSpinner.Value = 1;
            % Show the figure after all components are created
            app.mixerFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = musicmixer_mlo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mixerFig)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mixerFig)
        end
    end
end